classdef GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        q1SpinnerLabel       matlab.ui.control.Label
        q1Spinner            matlab.ui.control.Spinner
        q2SpinnerLabel       matlab.ui.control.Label
        q2Spinner            matlab.ui.control.Spinner
        q3SpinnerLabel       matlab.ui.control.Label
        q3Spinner            matlab.ui.control.Spinner
        q4SpinnerLabel       matlab.ui.control.Label
        q4Spinner            matlab.ui.control.Spinner
        q5SpinnerLabel       matlab.ui.control.Label
        q5Spinner            matlab.ui.control.Spinner
        q6SpinnerLabel       matlab.ui.control.Label
        q6Spinner            matlab.ui.control.Spinner
        ArticulacionesLabel  matlab.ui.control.Label
        XYZRPYListBoxLabel   matlab.ui.control.Label
        XYZRPYListBox        matlab.ui.control.ListBox
        RightPanel           matlab.ui.container.Panel
        qSliderLabel         matlab.ui.control.Label
        qSlider              matlab.ui.control.Slider
        AnimarButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Robot = 0; % Description
        Q = evalin('base','q');
        q = zeros([1 6]);
    end
    
    methods (Access = private)
        
        function results = updatePlot(app)
            coord = app.Robot.fkine(app.q);
            rpy = tr2rpy(coord);
            split(num2str([coord.t' rpy]));
            app.XYZRPYListBox.Items = split(num2str([coord.t' rpy*180/pi]));
            
            app.Robot.plot(app.q,'workspace',[-1000 2000 -1500 1500 0 2000],'noa', 'view',[30 30])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            qsize=size(app.Q);
            app.qSlider.Limits = [1,qsize(1)];
            
            L1=380;
            L2=820;
            L3=880;
            L(1) = Link('revolute','alpha',0,'a',0,'d',L1,'offset',0, 'qlim',[-pi pi],'modified');
            L(2) = Link('revolute','alpha',  -pi/2,  'a', 0,'d', 0,'offset',-pi/2, 'qlim',[-pi pi],'modified');
            L(3) = Link('revolute','alpha',      0,  'a',L2,'d', 0,'offset',    0, 'qlim',[-67/180*pi 247*pi/180],'modified');
            L(4) = Link('revolute','alpha',  -pi/2,  'a', 0,'d',L3,'offset',    0, 'qlim',[-210*pi/180 210*pi/180],'modified');
            L(5) = Link('revolute','alpha',   pi/2,  'a', 0,'d', 0,'offset',    0, 'qlim',[-pi pi],'modified');
            L(6) = Link('revolute','alpha',  -pi/2,  'a', 0,'d', 0,'offset',    0, 'qlim',[-210*pi/180 210*pi/180],'modified');
            app.Robot=SerialLink(L,'name','Yaskawa HC20XP');
            
            mth_tool = rt2tr(eye(3),[0 0 0]);
            app.Robot.tool = mth_tool;
            
            figure(1)
            xyz = evalin('base','xyz');
            plot3(xyz(1,:),xyz(2,:),xyz(3,:))
            axis([-2000 2000 -2000 2000 0 4000])
            hold on
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {347, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(1) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(2) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(3) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(4) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(5) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(6) = changingValue*pi/180;
            updatePlot(app);
        end

        % Value changing function: qSlider
        function qSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.q1Spinner.Value = app.Q(changingValue,1)/pi*180;
            app.q2Spinner.Value = app.Q(changingValue,2)/pi*180;
            app.q3Spinner.Value = app.Q(changingValue,3)/pi*180;
            app.q4Spinner.Value = app.Q(changingValue,4)/pi*180;
            app.q5Spinner.Value = app.Q(changingValue,5)/pi*180;
            app.q6Spinner.Value = app.Q(changingValue,6)/pi*180;
            app.q = app.Q(changingValue,:);
            updatePlot(app);
        end

        % Button pushed function: AnimarButton
        function AnimarButtonPushed(app, event)
            app.Robot.plot(app.Q,'noa')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.q1SpinnerLabel = uilabel(app.LeftPanel);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.LeftPanel);
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.Limits = [-180 180];
            app.q2SpinnerLabel = uilabel(app.LeftPanel);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.LeftPanel);
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-180 180];
            app.q3SpinnerLabel = uilabel(app.LeftPanel);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.LeftPanel);
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-67 247];
            app.q4SpinnerLabel = uilabel(app.LeftPanel);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.LeftPanel);
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-210 210];
            app.q5SpinnerLabel = uilabel(app.LeftPanel);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.LeftPanel);
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-180 180];
            app.q6SpinnerLabel = uilabel(app.LeftPanel);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.LeftPanel);
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-210 210];
            app.ArticulacionesLabel = uilabel(app.LeftPanel);
            app.ArticulacionesLabel.Text = 'Articulaciones';
            app.XYZRPYListBoxLabel = uilabel(app.LeftPanel);
            app.XYZRPYListBoxLabel.Text = {'X'; 'Y'; 'Z'; 'R'; 'P'; 'Y'};
            app.XYZRPYListBox = uilistbox(app.LeftPanel);
            app.XYZRPYListBox.Items = {'0', '0', '0', '0', '0', '0'};
            app.XYZRPYListBox.Value = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.qSliderLabel = uilabel(app.RightPanel);
            app.qSliderLabel.Text = 'q';
            app.qSlider = uislider(app.RightPanel);
            app.qSlider.Limits = [1 100];
            app.qSlider.ValueChangingFcn = createCallbackFcn(app, @qSliderValueChanging, true);
            app.qSlider.Value = 1;
            app.AnimarButton = uibutton(app.RightPanel, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @AnimarButtonPushed, true);
            app.AnimarButton.Text = 'Animar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end